classdef hydrophone_scanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NormalScanTab                   matlab.ui.container.Tab
        datasavelocationEditField       matlab.ui.control.EditField
        datasavelocationEditFieldLabel  matlab.ui.control.Label
        ofdatapointstocollectonaxisLabel  matlab.ui.control.Label
        StartScanWifiLineButton         matlab.ui.control.Button
        StartScanLineButton             matlab.ui.control.Button
        onzEditField                    matlab.ui.control.EditField
        onzEditFieldLabel               matlab.ui.control.Label
        onyEditField                    matlab.ui.control.EditField
        onyEditFieldLabel               matlab.ui.control.Label
        onxEditField                    matlab.ui.control.EditField
        onxEditFieldLabel               matlab.ui.control.Label
        z_lengthEditField               matlab.ui.control.EditField
        z_lengthEditFieldLabel          matlab.ui.control.Label
        y_lengthEditField               matlab.ui.control.EditField
        y_lengthEditFieldLabel          matlab.ui.control.Label
        x_lengthEditField               matlab.ui.control.EditField
        x_lengthEditFieldLabel          matlab.ui.control.Label
        xyzinformationmakesuretotypeinorderLabel  matlab.ui.control.Label
        D2D3DLabel                      matlab.ui.control.Label
        GenerateGraphButton             matlab.ui.control.Button
        StartScanNormalButton           matlab.ui.control.Button
        AxisEditField                   matlab.ui.control.EditField
        AxisEditFieldLabel              matlab.ui.control.Label
        ModeEditField                   matlab.ui.control.EditField
        ModeEditFieldLabel              matlab.ui.control.Label
        DeviceStatusTab                 matlab.ui.container.Tab
        RunScanButton                   matlab.ui.control.Button
        OscilloscopeLamp                matlab.ui.control.Lamp
        OscilloscopeLampLabel           matlab.ui.control.Label
        WifiLamp                        matlab.ui.control.Lamp
        WifiLampLabel                   matlab.ui.control.Label
        FunctionGeneratorLamp           matlab.ui.control.Lamp
        FunctionGeneratorLampLabel      matlab.ui.control.Label
        ArduinoLamp                     matlab.ui.control.Lamp
        ArduinoLampLabel                matlab.ui.control.Label
        DataRefineTab                   matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartScanNormalButton
        function StartScanNormalButtonPushed(app, event)
        clear_file


             input_dimension  = app.ModeEditField.Value;
             input_axis =  app.AxisEditField.Value;
      
             len_x = app.x_lengthEditField.Value;
             len_y = app.y_lengthEditField.Value;
             len_z = app.z_lengthEditField.Value;

             step_x = app.onxEditField.Value;
             step_y = app.onyEditField.Value;
             step_z = app.onzEditField.Value;


             write_file(input_dimension);
             write_file(input_axis);
             write_file(len_x);
             write_file(len_y);
             write_file(len_z);

             write_file(step_x);
             write_file(step_y);
             write_file(step_z);


%%% The start of the helper function area


%%%%%%_________________________________________________________________________________________________________________%%%%%
%%Helper function to write in aaa.txt information of current scan
            function clear_file()
                % Specify the full path to the text file
                filePath = 'C:\Users\shenj\Desktop\hydrophone_data\aaa.txt';
                
                % Open the file for appending ('a' mode adds to the file without overwriting)
                fileID = fopen(filePath, 'w');  % Use 'w' mode to overwrite the file instead of appending
                
                % Check if the file was opened successfully
                if fileID == -1
                    error('Failed to open the file.');
                end
                
                % Write information to the file
                fprintf(fileID, '');
                
                % Close the file
                fclose(fileID);
                
                disp('Information successfully written to aaa.txt on the desktop.');

            end
%%%%%%_________________________________________________________________________________________________________________%%%%%


%%%%%%_________________________________________________________________________________________________________________%%%%%
%% helper function that writes in informaiton
            function write_file(text)
                % Specify the full path to the text file
                filePath = 'C:\Users\shenj\Desktop\hydrophone_data\aaa.txt';
                
                % Open the file for appending ('a' mode adds to the file without overwriting)
                fileID = fopen(filePath, 'a');  % Use 'w' mode to overwrite the file instead of appending
                
                % Check if the file was opened successfully
                if fileID == -1
                    error('Failed to open the file.');
                end
                
                % Write information to the file
                fprintf(fileID, '%s\n', text);
                
                % Close the file
                fclose(fileID);
                
                disp('Information successfully written to aaa.txt on the desktop.');

            end
%%%%%%_________________________________________________________________________________________________________________%%%%%


        end

        % Button pushed function: StartScanLineButton
        function StartScanLineButtonPushed(app, event)
        clear_file


             input_dimension  = app.ModeEditField.Value;
             input_axis =  app.AxisEditField.Value;
      
             len_x = app.onxEditField.Value;
             len_y = app.onyEditField.Value;
             len_z = app.onxEditField.Value;

             step_x = app.onxEditField.Value;
             step_y = app.onyEditField.Value;
             step_z = app.onzEditField.Value;


             write_file(input_dimension);
             write_file(input_axis);
             write_file(len_x);
             write_file(len_y);
             write_file(len_z);

             write_file(step_x);
             write_file(step_y);
             write_file(step_z);

%% Choosing scan type to start
if strcmp(input_dimension, "1D")
    oneD_linescan(input_axis,len_x,len_y,len_z,step_x,step_y,step_z);
elseif strcmp(input_dimension, "2D")
    twoD_linescan(input_axis,len_x,len_y,len_z,step_x,step_y,step_z);
elseif strcmp(input_dimension, "3D")
    threeD_linescan(input_axis,len_x,len_y,len_z,step_x,step_y,step_z);
else 
    error('Wrong input dimension type');
end


%%% The start of the helper function area


%%%%%%_________________________________________________________________________________________________________________%%%%%
%%Helper function to write in aaa.txt information of current scan
            function clear_file()
                % Specify the full path to the text file
                filePath = 'C:\Users\shenj\Desktop\hydrophone_data\aaa.txt';
                
                % Open the file for appending ('a' mode adds to the file without overwriting)
                fileID = fopen(filePath, 'w');  % Use 'w' mode to overwrite the file instead of appending
                
                % Check if the file was opened successfully
                if fileID == -1
                    error('Failed to open the file.');
                end
                
                % Write information to the file
                fprintf(fileID, '');
                
                % Close the file
                fclose(fileID);
                
                disp('Information successfully written to aaa.txt on the desktop.');

            end
%%%%%%_________________________________________________________________________________________________________________%%%%%


%%%%%%_________________________________________________________________________________________________________________%%%%%
%% helper function that writes in informaiton
            function write_file(text)
                % Specify the full path to the text file
                filePath = 'C:\Users\shenj\Desktop\hydrophone_data\aaa.txt';
                
                % Open the file for appending ('a' mode adds to the file without overwriting)
                fileID = fopen(filePath, 'a');  % Use 'w' mode to overwrite the file instead of appending
                
                % Check if the file was opened successfully
                if fileID == -1
                    error('Failed to open the file.');
                end
                
                % Write information to the file
                fprintf(fileID, '%s\n', text);
                
                % Close the file
                fclose(fileID);
                
                disp('Information successfully written to aaa.txt on the desktop.');

            end
%%%%%%_________________________________________________________________________________________________________________%%%%%
%% the function that is realted to 1D scanning
            function oneD_linescan(input_axis,len_x,len_y,len_z,step_x,step_y,step_z)
                if (input_axis == 'x')
                    oneD_scan(input_axis,step_x,len_x);
                elseif (input_axis == 'y')
                    oneD_scan(input_axis,step_y,len_y);
                elseif (input_axis == 'z')
                    oneD_scan(input_axis,step_z,len_z);
                else
                    error('1D axis input wrong');
                end
            end

%%  helper funciton of 1D scanning function
  function oneD_scan(input_axis_1D, step_size_1D, data_points_number)
    init_helper;
    step_amount_1D = data_points_number - 1;
    data_generator('1');
    
    motor_mover_line(step_size_1D,input_axis_1D,step_amount_1D);

    for i= 2:step_amount_1D
            
            i_str = num2str(i); % Convert integer i
            data_generator(i_str);

    end
   clear_helper;
  

end

%%%%%%_________________________________________________________________________________________________________________%%%%%


%%%%%%_________________________________________________________________________________________________________________%%%%%
%% the function that is realted to 2D scanning
 function twoD_linescan(input_axis_2D,len_a,len_b,step_a,step_b)
 init_helper;
 if strcmp(input_axis_2D, 'xy') 
                a_axis = 'x';
                b_axis = 'y';
            elseif strcmp(input_axis_2D, 'yz') 
                a_axis = 'y';
                b_axis = 'z';
            elseif strcmp(input_axis_2D, 'xz') 
                a_axis = 'x';
                b_axis = 'z';
  end
             step_size_b = step_b;
             step_size_a =  step_a;
             revol_a = len_a;
             revol_b = len_b;
             j = 1;

             %% First collect at the origin
               for i= 1:revol_a-1
                    
                    if mod(i, 2) == 1
                    str_start = [num2str(i) '_1'];
                    data_generator(str_start);
                    motor_mover_line(step_size_b,b_axis,revol_b-1);
                    for j= 2:revol_b
                        str = [num2str(i) '_' num2str(j)];
                        data_generator(str);
                        %%pause(); %% this needs to be change to sync oscilloscope
                        %%pause is in Arduino
                    end
                    
                    else
                    str_start = [num2str(i) '_' num2str(revol_b+1)];
                    data_generator(str_start);
                    motor_mover_line(step_size_1D,input_axis_1D,revol_b-1);
                    for j= 2:revol
                        w = revol_b - j + 2;
                        str = [num2str(i) '_' num2str(w)];
                        data_generator(str);
                        %%pause(); %% this needs to be change to sync oscilloscope
                        %%pause is in Arduino

                    end
                    end
                    pause(1);
                    if i < revol_a
                       motor_mover_point(step_size_a,a_axis);
                       
                       step_size_b = -step_size_b;
                    end
               end
            
    %%           twoD_plothelper(revol_a,revol_b);
               clear_helper;

end

%%%%%%_________________________________________________________________________________________________________________%%%%%


%% shared helper funcitons

%% function to initialize ports
function init_helper()    

%% open Arduino communication ports
    s = serialport("COM6", 115200);
    s.OutputBufferSize = 20;

%% open funciton generator port
    visainst = visa('ni', 'USB0::0x0957::0x1507::MY48003157::0');
    fopen(visainst);

%% open oscilloscope
   mso4000 = visa( 'ni','USB0::0x1AB1::0x04B1::DS4A175000664::0::INSTR' );
   mso4000.InputBufferSize = 2048;
   fopen(mso4000);

   pause(2);
end

%% function to clear ports
function clear_helper() 
        clear s;
        fclose(visainst);
        fclose( mso4000 );
end


%% function to move collect data on a single line
function motor_mover_line(step_size_1D,input_axis_1D,step_amount_1D)
    data = '';
    
if (input_axis_1D == 'x')
    a = 1;
elseif (input_axis_1D == 'y')
    a = 2;
elseif (input_axis_1D == 'z')
    a = 3;
end


if (step_size > 0) 
    c = 1;
    intermediate  = sprintf('%d,%d,%d,%d', a, step_size_1D, c, step_amount_1D);
 else
    % Pad the step_size with zeros
    c = 0;
    intermediate = sprintf('%d,%d,%d,%d', a, abs(step_size_1D), c, step_amount_1D);
 end

message = ['s,', intermediate];


pause(2);

writeline(s, message);
    while true
        if strlength(data) == 0
            data = readline(s);
        else 
            
            disp(data);
            break;
        end


    end
end


        end

        % Button pushed function: StartScanWifiLineButton
        function StartScanWifiLineButtonPushed(app, event)
        clear_file


             input_dimension  = app.ModeEditField.Value;
             input_axis =  app.AxisEditField.Value;
      
             len_x = app.x_lengthEditField.Value;
             len_y = app.y_lengthEditField.Value;
             len_z = app.z_lengthEditField.Value;

             step_x = app.onxEditField.Value;
             step_y = app.onyEditField.Value;
             step_z = app.onzEditField.Value;


             write_file(input_dimension);
             write_file(input_axis);
             write_file(len_x);
             write_file(len_y);
             write_file(len_z);

             write_file(step_x);
             write_file(step_y);
             write_file(step_z);


%%% The start of the helper function area


%%%%%%_________________________________________________________________________________________________________________%%%%%
%%Helper function to write in aaa.txt information of current scan
            function clear_file()
                % Specify the full path to the text file
                filePath = 'C:\Users\shenj\Desktop\hydrophone_data\aaa.txt';
                
                % Open the file for appending ('a' mode adds to the file without overwriting)
                fileID = fopen(filePath, 'w');  % Use 'w' mode to overwrite the file instead of appending
                
                % Check if the file was opened successfully
                if fileID == -1
                    error('Failed to open the file.');
                end
                
                % Write information to the file
                fprintf(fileID, '');
                
                % Close the file
                fclose(fileID);
                
                disp('Information successfully written to aaa.txt on the desktop.');

            end
%%%%%%_________________________________________________________________________________________________________________%%%%%


%%%%%%_________________________________________________________________________________________________________________%%%%%
%% helper function that writes in informaiton
            function write_file(text)
                % Specify the full path to the text file
                filePath = 'C:\Users\shenj\Desktop\hydrophone_data\aaa.txt';
                
                % Open the file for appending ('a' mode adds to the file without overwriting)
                fileID = fopen(filePath, 'a');  % Use 'w' mode to overwrite the file instead of appending
                
                % Check if the file was opened successfully
                if fileID == -1
                    error('Failed to open the file.');
                end
                
                % Write information to the file
                fprintf(fileID, '%s\n', text);
                
                % Close the file
                fclose(fileID);
                
                disp('Information successfully written to aaa.txt on the desktop.');

            end
%%%%%%_________________________________________________________________________________________________________________%%%%%

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NormalScanTab = uitab(app.TabGroup);
            app.NormalScanTab.Title = 'Normal Scan';
            app.ModeEditFieldLabel = uilabel(app.NormalScanTab);
            app.ModeEditFieldLabel.Text = 'Mode';
            app.ModeEditField = uieditfield(app.NormalScanTab, 'text');
            app.AxisEditFieldLabel = uilabel(app.NormalScanTab);
            app.AxisEditFieldLabel.Text = 'Axis';
            app.AxisEditField = uieditfield(app.NormalScanTab, 'text');
            app.StartScanNormalButton = uibutton(app.NormalScanTab, 'push');
            app.StartScanNormalButton.ButtonPushedFcn = createCallbackFcn(app, @StartScanNormalButtonPushed, true);
            app.StartScanNormalButton.Text = 'Start Scan Normal';
            app.GenerateGraphButton = uibutton(app.NormalScanTab, 'push');
            app.GenerateGraphButton.Text = 'Generate Graph';
            app.D2D3DLabel = uilabel(app.NormalScanTab);
            app.D2D3DLabel.Text = '1D/2D/3D';
            app.xyzinformationmakesuretotypeinorderLabel = uilabel(app.NormalScanTab);
            app.xyzinformationmakesuretotypeinorderLabel.Text = {'x,y,z information,make sure '; 'to type in order'};
            app.x_lengthEditFieldLabel = uilabel(app.NormalScanTab);
            app.x_lengthEditFieldLabel.Text = 'x_length';
            app.x_lengthEditField = uieditfield(app.NormalScanTab, 'text');
            app.y_lengthEditFieldLabel = uilabel(app.NormalScanTab);
            app.y_lengthEditFieldLabel.Text = 'y_length';
            app.y_lengthEditField = uieditfield(app.NormalScanTab, 'text');
            app.z_lengthEditFieldLabel = uilabel(app.NormalScanTab);
            app.z_lengthEditFieldLabel.Text = 'z_length';
            app.z_lengthEditField = uieditfield(app.NormalScanTab, 'text');
            app.onxEditFieldLabel = uilabel(app.NormalScanTab);
            app.onxEditFieldLabel.Text = '# on x';
            app.onxEditField = uieditfield(app.NormalScanTab, 'text');
            app.onyEditFieldLabel = uilabel(app.NormalScanTab);
            app.onyEditFieldLabel.Text = '# on y';
            app.onyEditField = uieditfield(app.NormalScanTab, 'text');
            app.onzEditFieldLabel = uilabel(app.NormalScanTab);
            app.onzEditFieldLabel.Text = '# on z';
            app.onzEditField = uieditfield(app.NormalScanTab, 'text');
            app.StartScanLineButton = uibutton(app.NormalScanTab, 'push');
            app.StartScanLineButton.ButtonPushedFcn = createCallbackFcn(app, @StartScanLineButtonPushed, true);
            app.StartScanLineButton.Text = 'Start Scan Line';
            app.StartScanWifiLineButton = uibutton(app.NormalScanTab, 'push');
            app.StartScanWifiLineButton.ButtonPushedFcn = createCallbackFcn(app, @StartScanWifiLineButtonPushed, true);
            app.StartScanWifiLineButton.Text = 'Start Scan Wifi Line';
            app.ofdatapointstocollectonaxisLabel = uilabel(app.NormalScanTab);
            app.ofdatapointstocollectonaxisLabel.Text = {'# of data points to collect on'; 'axis'};
            app.datasavelocationEditFieldLabel = uilabel(app.NormalScanTab);
            app.datasavelocationEditFieldLabel.Text = 'data save location';
            app.datasavelocationEditField = uieditfield(app.NormalScanTab, 'text');
            app.DeviceStatusTab = uitab(app.TabGroup);
            app.DeviceStatusTab.Title = 'Device Status';
            app.ArduinoLampLabel = uilabel(app.DeviceStatusTab);
            app.ArduinoLampLabel.Text = 'Arduino';
            app.ArduinoLamp = uilamp(app.DeviceStatusTab);
            app.FunctionGeneratorLampLabel = uilabel(app.DeviceStatusTab);
            app.FunctionGeneratorLampLabel.Text = 'Function Generator';
            app.FunctionGeneratorLamp = uilamp(app.DeviceStatusTab);
            app.WifiLampLabel = uilabel(app.DeviceStatusTab);
            app.WifiLampLabel.Text = 'Wifi';
            app.WifiLamp = uilamp(app.DeviceStatusTab);
            app.OscilloscopeLampLabel = uilabel(app.DeviceStatusTab);
            app.OscilloscopeLampLabel.Text = 'Oscilloscope';
            app.OscilloscopeLamp = uilamp(app.DeviceStatusTab);
            app.RunScanButton = uibutton(app.DeviceStatusTab, 'push');
            app.RunScanButton.Text = 'Run Scan';
            app.DataRefineTab = uitab(app.TabGroup);
            app.DataRefineTab.Title = 'Data Refine';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hydrophone_scanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end