classdef my_scanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DisplayfirstFFTButton         matlab.ui.control.Button
        HzLabel                       matlab.ui.control.Label
        Input_FFTFILTEREditField      matlab.ui.control.EditField
        Input_FFTFILTERLabel          matlab.ui.control.Label
        RelocatelocationbyuserButton  matlab.ui.control.Button
        LowpassFFTButton              matlab.ui.control.Button
        Infoonstep_sizeoneachaxisinUnitstepLabel  matlab.ui.control.Label
        Step_sizeEditField            matlab.ui.control.EditField
        Step_sizeEditFieldLabel       matlab.ui.control.Label
        Threelengthrepresentionmovementofmotorsonxyzex0750Label  matlab.ui.control.Label
        LengthEditField               matlab.ui.control.EditField
        LengthEditFieldLabel          matlab.ui.control.Label
        three01inorderxyzex011meansyzLabel  matlab.ui.control.Label
        AxisEditField                 matlab.ui.control.EditField
        AxisEditFieldLabel            matlab.ui.control.Label
        D2D3DLabel                    matlab.ui.control.Label
        ModeEditField                 matlab.ui.control.EditField
        ModeEditFieldLabel            matlab.ui.control.Label
        AverageDiagramButton          matlab.ui.control.Button
        HighpassFFTButton             matlab.ui.control.Button
        StartScanButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartScanButton
        function StartScanButtonPushed(app, event)
             input_dimension  = app.ModeEditField.Value;
             input_axis =  app.AxisEditField.Value;
             input_string_len = app.LengthEditField.Value;
             input_string_step = app.Step_sizeEditField.Value;


             step_sizes_cell_len = strsplit(input_string_len, ',');        
             len_x = str2double(step_sizes_cell_len{1});
             len_y = str2double(step_sizes_cell_len{2});
             len_z = str2double(step_sizes_cell_len{3}); 

             step_sizes_cell = strsplit(input_string_step, ',');


             step_x = str2double(step_sizes_cell{1});
             step_y = str2double(step_sizes_cell{2});
             step_z = str2double(step_sizes_cell{3});

% clear previous thing and record current movement
fid = fopen('C:\Users\Jinzhi Shen\Desktop\fusf_redo\aaa.txt', 'w');

if fid == -1
    error('Unable to open file for writing');
end

% Write len_x, len_y, len_z to the file
fprintf(fid, 'len_x: %d\n', len_x);
fprintf(fid, 'len_y: %d\n', len_y);
fprintf(fid, 'len_z: %d\n', len_z);

% Close the file
fclose(fid);

             % Check the input dimension
if strcmp(input_dimension, "1D")

 %% basic judge on whether this 1D scan could be performed
 

 %% judgement logic ends

 
 %    disp('Initiate 1D scan');

     if strcmp(input_axis, "100")
         input_axis_1D = "x";
          step_size = step_x;
          total_length = len_x;
     elseif strcmp(input_axis, "010")
         input_axis_1D = "y";
          step_size = step_y;
          total_length = len_y;
     elseif strcmp(input_axis, "001")
         input_axis_1D = "z";
          step_size = step_z;
          total_length = len_z;
     end

     oneD_scan(input_axis_1D, step_size, total_length);
    
    elseif strcmp(input_dimension, "2D")
         %%disp('Initiate 2D scan');
         if strcmp(input_axis, "110")
             input_axis_2D = "xy";
             len_a = len_x;
             len_b = len_y;
             step_a = step_x;
             step_b = step_y;
         elseif strcmp(input_axis, "101")
             input_axis_2D = "xz";
             len_a = len_x;
             len_b = len_z;
             step_a = step_x;
             step_b = step_z;
         elseif strcmp(input_axis, "011")
             input_axis_2D = "yz";
             len_a = len_y;
             len_b = len_z;
             step_a = step_y;
             step_b = step_z;
         end
          
          twoD_scan(input_axis_2D,len_a,len_b,step_a,step_b)
          %% for 2D scan, we will begin to apply the global variable usage
    
    elseif strcmp(input_dimension, "3D")
         disp('Initiate 3D scan');
         threeD_scan(len_x,len_y,len_z,step_x,step_y,step_z);
    else
         disp('Error input, quit the program');
end 

%% the function that do the 1D scan, the input to this function is 
%% info_axis : choosing it is x-scan, y-scan or z-scan
%% step_size_1D : choosing the step size of each step
%% total_length : the steps that we should take to move in 1D
function oneD_scan(input_axis_1D, step_size_1D, total_length)
    revol = round(total_length/abs(step_size_1D)); %% take the whole number to do whole rounds of scan
    data_generator('1');
    for i= 2:revol
            
            i_str = num2str(i); % Convert integer i to a string
            motor_mover(step_size_1D,input_axis_1D);
            data_generator(i_str);

    end

    oneD_plothelper(revol);

end


function twoD_scan(input_axis_2D,len_a,len_b,step_a,step_b)

 if strcmp(input_axis_2D, 'xy') 
                a_axis = 'x';
               
                b_axis = 'y';
            elseif strcmp(input_axis_2D, 'yz') 
                a_axis = 'y';
                b_axis = 'z';
            elseif strcmp(input_axis_2D, 'xz') 
                a_axis = 'x';
                b_axis = 'z';
            end
             step_size_b = step_b;
             step_size_a =  step_a;
             revol_a = abs(len_a/step_a);
             revol_b = abs(len_b/step_b);
            j = 1;
               for i= 1:revol_a
                    if mod(i, 2) == 0
                    str = [num2str(i) '_' num2str(revol_b)];
                    else
                    str = [num2str(i) '_' num2str(1)];
                    end
                    data_generator(str);
                   for j = 2: revol_b
                       if mod(i, 2) == 0
                           w = revol_b - j + 1;
                           str = [num2str(i) '_' num2str(w)];
                        else
                         str = [num2str(i) '_' num2str(j)];
                        end
                       motor_mover(step_size_b,b_axis);
                   
                       data_generator(str);
            
                      
                   end
                       motor_mover(step_size_a,a_axis);
                       
                       step_size_b = -step_size_b;
               end
            
               twoD_plothelper(revol_a,revol_b);

end

%%%%%%%%%%
% start of helper functions
%%%%%%

%% redefine the function for both use since no matter 1D, 2D, 3D we all only move motor on one axis at a time
%% name for 1D will be 1,2,3,4...
%% name for 2D will be 1_1,1_2, ...
%% name for 3D will be 1_1_1, 1_1_2, ...

function motor_mover(step_size,input_axis_1D)
s = serialport("COM6", 115200);
    s.OutputBufferSize = 20;
    data = '';
    if (step_size > 0) 
    padded_step_size = num2str(step_size,'%04.f');
    else
    % Pad the step_size with zeros
    a = num2str(abs(step_size), '%03.f');

    % Add '1' at the beginning of the padded string
    padded_step_size = ['1', a(end-2:end)];
    end

    %%1:+x 2:-x 3:+y 4:-y 5:+z 6:-z 
    %% change here for different operation
    if strcmp(input_axis_1D,"x")
        message = [padded_step_size, ',0000,0000'];
    elseif strcmp(input_axis_1D, "y")
        message = ['0000,', padded_step_size,',0000'];
    elseif strcmp(input_axis_1D, "z")
        message = ['0000,', '0000,',padded_step_size];
    end

pause(2);

writeline(s, message);
    while true
        if strlength(data) == 0
            data = readline(s);
        else 
            
            disp(data);
            break;
        end


    end
end
    %% motor motion end


 %% Fun gen sending burst signal begin
    function data_generator(round_num)
    visainst = visa('ni', 'USB0::0x0957::0x1507::MY48003157::0');

    fopen(visainst);

    % set the wave for the square, 10 khz, 5vpp, 30% duty cycle
    fprintf(visainst, 'TRIG:SOUR BUS;*TRG;*WAI;*TRG;*WAI');
    fclose(visainst);


    %% Fun gen sending burst signal end

    %% Scanning process begin
    %% start the laser scan on chanel3
   mso4000 = visa( 'ni','USB0::0x1AB1::0x04B1::DS4A175000664::0::INSTR' );
   mso4000.InputBufferSize = 2048;

   fopen(mso4000);
   flushinput(mso4000);
   %% checking in 100 data collected whether we received a laser trigger signal
   %% laser signal is around 10hz while the data acquisition rate is 4G/HZ which means we acquire 100 data 
   %% 40,000 kHz which is much larger than 10hz

    fprintf(mso4000, ':WAV:SOUR CHAN4' );

    fprintf(mso4000, ':wav:data?' );

    
    [data,len]= fread( mso4000, 2048);

    fclose( mso4000 );
    
    wave = data(12:len-1);

    % Define the folder name
    folderName = 'C:\Users\Jinzhi Shen\Desktop\fusf_redo\test_scan';

    % Ensure the folder exists, if not, create it
    if ~exist(folderName, 'dir')
        mkdir(folderName);
    end

    matFileName = fullfile(folderName, sprintf('matlab_%s.mat', round_num));

    
    % Save data to the MATLAB file
    save(matFileName, 'wave', 'len');
    %% scanning process end 
    
    end


    %% the helper function that helps to plot the oneD diagram
function oneD_plothelper(revol)
    % Store the maximum and minimum differences in a cell array
    differences = zeros(1, revol);

    % Iterate over each MATLAB variable
    for i = 1:revol
        % Load the MATLAB variable from the MAT file
        filename = fullfile('C:\Users\Jinzhi Shen\Desktop\fusf_redo\test_scan', sprintf('matlab_%d.mat', i));
        loaded_data = load(filename);  % Load the variable from the MAT file
        data = loaded_data.wave;

        max_val = max(data(:));
        min_val = min(data(:));

        val = max_val - min_val;
        differences(i) = val;
    end

    [X, Y] = meshgrid(1:revol, 1); 
    Z = zeros(size(X));  %%initialize the z matrix
    for i = 1:revol
        Z(:, i) = differences(i);  % euqals the value in z to the value in 
    end

    %plot the image with color bar
    imagesc(Z);  
    colorbar;

    % Note that currently for better viewing, the 1D diagram is also shown
    % as 2D plot 
    xlabel('Variable Index : step_size * 0.01 mm');
    ylabel('Y');
    title('1D ultrasound field');


    % Plot differences in normal stirng plot way
    figure;
    plot(differences, '-o', 'LineWidth', 2, 'MarkerSize', 10);
    xlabel('Variable Index');
    ylabel('Difference');
    title('Maximum - Minimum Differences');
    grid on; % add grid


end


function twoD_plothelper(revol_a, revol_b)
        differences = zeros(revol_a, revol_b);

    % Iterate over each MATLAB variable
    for i = 1:revol_a
        for j = 1:revol_b

        % Load the MATLAB variable from the MAT file
        filename = fullfile('C:\Users\Jinzhi Shen\Desktop\fusf_redo\test_scan', sprintf('matlab_%d_%d.mat', i,j));
        loaded_data = load(filename);  % Load the variable from the MAT file
        data = loaded_data.wave;

        max_val = max(data(:));
        min_val = min(data(:));

        val = max_val - min_val;
        differences(i,j) = val;
        end
    end
        [X, Y] = meshgrid(1:revol_b, 1:revol_a);
        
        
        % Plot the differences as a 2D color map
        imagesc(differences);
        
        % Add labels and colorbar
        xlabel('Column Index (revol_b)');
        ylabel('Row Index (revol_a)');
        colorbar;
        title('Differences Heatmap');
        
        figure;
        plot3(X(:), Y(:), differences(:), 'o');
        xlabel('Column Index (revol_b)');
        ylabel('Row Index (revol_a)');
        zlabel('Difference');
        title('Differences 3D Plot');
        
        % Create a surface plot
        figure;
        surf(X, Y, differences);
        xlabel('Column Index (revol_b)');
        ylabel('Row Index (revol_a)');
        zlabel('Difference');
        title('Differences Surface Plot');

end 


function threeD_scan(len_a,len_b,len_c,step_a,step_b,step_c)
        revol_a = abs(len_a/step_a);
        revol_b = abs(len_b/step_b);
        revol_c = abs(len_c/step_c);
        j = 1;
        
        step_size_b = step_b;
        step_size_a =  step_a;
        a_axis = 'x';
                     
        b_axis = 'y';
        for k = 1:revol_c %% scanning xy plane on z layers
           for i= 1:revol_a
                if mod(i, 2) == 0
                str = [num2str(i) '_' num2str(revol_b) '_' num2str(k)];
                else
                str = [num2str(i) '_' num2str(1) '_' num2str(k)];
                end
               data_generator(str);
               for j = 2: revol_b
                   if mod(i, 2) == 0
                       w = revol_b - j + 1;
                       str = [num2str(i) '_' num2str(w) '_' num2str(k)];
                    else
                     str = [num2str(i) '_' num2str(j) '_' num2str(k)];
                    end
                    motor_mover(step_size_a,b_axis);
                       pause(1.5);
                   data_generator(str);
        
                  
               end
                    motor_mover(step_size_a,a_axis);
                       pause(1.5);
                   step_size_b = -step_size_b;
           end
           motor_mover(step_c, 'z');
         end

end

        end

        % Button pushed function: HighpassFFTButton
        function HighpassFFTButtonPushed(app, event)
                       input_dimension  = app.ModeEditField.Value;
                                 input_axis =  app.AxisEditField.Value;
                                 input_string_len = app.LengthEditField.Value;
                                
                    
                    step_sizes_cell_len = strsplit(input_string_len, ',');        
                    len_x = str2double(step_sizes_cell_len{1});
                    len_y = str2double(step_sizes_cell_len{2});
                    len_z = str2double(step_sizes_cell_len{3});
                    
                    
                    %% the movement's direction is determined in step's being positive or negative
                                                                
                    input_string_step = app.Step_sizeEditField.Value;
                    
                    step_sizes_cell = strsplit(input_string_step, ',');
                    
                    
                    step_x = str2double(step_sizes_cell{1});
                    step_y = str2double(step_sizes_cell{2});
                    step_z = str2double(step_sizes_cell{3});
                    len_a = 0;
                    len_b = 0;
                    step_a = 0;
                    step_b = 0;
                    if strcmp(input_dimension, "2D")
                         %%disp('Initiate 2D scan');
                         if strcmp(input_axis, "110")
                             input_axis_2D = "xy";
                             len_a = len_x;
                             len_b = len_y;
                             step_a = step_x;
                             step_b = step_y;
                         elseif strcmp(input_axis, "101")
                             input_axis_2D = "xz";
                             len_a = len_x;
                             len_b = len_z;
                             step_a = step_x;
                             step_b = step_z;
                         elseif strcmp(input_axis, "011")
                             input_axis_2D = "yz";
                             len_a = len_y;
                             len_b = len_z;
                             step_a = step_y;
                             step_b = step_z;
                         end
                                end
                                revol_a = len_a/step_a;
                                revol_b = len_b/step_b;
                                twoD_hifft(revol_a,revol_b);


            function twoD_hifft(revol_a, revol_b)
                differences = zeros(revol_a, revol_b);

                % Iterate over each MATLAB variable
                for i = 1:revol_a
                    for j = 1:revol_b
            
                    % Load the MATLAB variable from the MAT file
                    filename = fullfile('C:\Users\Jinzhi Shen\Desktop\fusf_redo\test_scan', sprintf('matlab_%d_%d.mat', i,j));
                    loaded_data = load(filename);  % Load the variable from the MAT file
                    wave = loaded_data.wave;
            
                    wave = wave';
                    N = 2048; % Length of FFT
                    cutoffFreq = 5; % Example cutoff frequency (Hz)
                    sampling_rate = 1; % Example sampling rate (Hz)
                    cutoffFreq = 0.000005; 
                    fftSpec = fft(wave', N);
            
                    freqAxis = (0:N-1) * (sampling_rate / N);
            
                    % Apply low-pass filter: zero out frequency components above the cutoff frequency
                    high_freq_indices = freqAxis < cutoffFreq;
                    fftSpec(high_freq_indices) = 0;
            
                    restored_wave = ifft(fftSpec, 'symmetric');
            
                    max_val = max( restored_wave(1:1400));
                    min_val = min( restored_wave(1:1400));
            
                    val = max_val - min_val;
                    differences(i,j) = val;
                    end
                end


                [X, Y] = meshgrid(1:revol_b, 1:revol_a);
                % Reverse the y-axis direction
                %%differences = flipud(differences);
                
                % Plot the differences as a 2D color map
                imagesc(differences);
                
                % Add labels and colorbar
                xlabel('Column Index y 5 * 0.01 scale');
                ylabel('Row Index z 5 * 0.01 scale');
                colorbar;
                title('Differences Heatmap');
                
                figure;
                plot3(X(:), Y(:), differences(:), 'o');
                xlabel('Column Index (revol_b)');
                ylabel('Row Index (revol_a)');
                zlabel('Difference');
                title('Differences 3D Plot');
                
                % Create a surface plot
                figure;
                surf(X, Y, differences);
                xlabel('Column Index (revol_b)');
                ylabel('Row Index (revol_a)');
                zlabel('Difference');
                title('Differences Surface Plot');
            end
                                

        end

        % Button pushed function: LowpassFFTButton
        function LowpassFFTButtonPushed(app, event)
                       input_dimension  = app.ModeEditField.Value;
                       input_axis =  app.AxisEditField.Value;
                       input_string_len = app.LengthEditField.Value;
                       input_fft  = app.Input_FFTFILTEREditField;
                       
                    
                    step_sizes_cell_len = strsplit(input_string_len, ',');        
                    len_x = str2double(step_sizes_cell_len{1});
                    len_y = str2double(step_sizes_cell_len{2});
                    len_z = str2double(step_sizes_cell_len{3});

                    input_fft_num = str2double(input_fft);
                    
                    
                    %% the movement's direction is determined in step's being positive or negative
                                                                
                    input_string_step = app.Step_sizeEditField.Value;
                    
                    step_sizes_cell = strsplit(input_string_step, ',');
                    
                    
                    step_x = str2double(step_sizes_cell{1});
                    step_y = str2double(step_sizes_cell{2});
                    step_z = str2double(step_sizes_cell{3});
                    len_a = 0;
                    len_b = 0;
                    step_a = 0;
                    step_b = 0;
                    if strcmp(input_dimension, "2D")
                         %%disp('Initiate 2D scan');
                         if strcmp(input_axis, "110")
                             input_axis_2D = "xy";
                             len_a = len_x;
                             len_b = len_y;
                             step_a = step_x;
                             step_b = step_y;
                         elseif strcmp(input_axis, "101")
                             input_axis_2D = "xz";
                             len_a = len_x;
                             len_b = len_z;
                             step_a = step_x;
                             step_b = step_z;
                         elseif strcmp(input_axis, "011")
                             input_axis_2D = "yz";
                             len_a = len_y;
                             len_b = len_z;
                             step_a = step_y;
                             step_b = step_z;
                         end
                                end
                                revol_a = len_a/step_a;
                                revol_b = len_b/step_b;
                                twoD_hifft(revol_a,revol_b);


            function twoD_hifft(revol_a, revol_b)
                differences = zeros(revol_a, revol_b);

                % Iterate over each MATLAB variable
                for i = 1:revol_a
                    for j = 1:revol_b
            
                    % Load the MATLAB variable from the MAT file
                    filename = fullfile('C:\Users\Jinzhi Shen\Desktop\fusf_redo\test_scan', sprintf('matlab_%d_%d.mat', i,j));
                    loaded_data = load(filename);  % Load the variable from the MAT file
                    wave = loaded_data.wave;
            
                    wave = wave';
                    N = 2048; % Length of FFT
                    sampling_rate = 1;
                    cutoffFreq = input_fft_num; 
                    fftSpec = fft(wave', N);
            
                    freqAxis = (0:N-1) * (sampling_rate / N);
            
                    % Apply low-pass filter: zero out frequency components above the cutoff frequency
                    high_freq_indices = freqAxis > cutoffFreq;
                    fftSpec(high_freq_indices) = 0;
            
                    restored_wave = ifft(fftSpec, 'symmetric');
            
                    max_val = max( restored_wave(1:1400));
                    min_val = min( restored_wave(1:1400));
            
                    val = max_val - min_val;
                    differences(i,j) = val;
                    end
                end


                [X, Y] = meshgrid(1:revol_b, 1:revol_a);
                % Reverse the y-axis direction
                %%differences = flipud(differences);
                
                % Plot the differences as a 2D color map
                imagesc(differences);
                
                % Add labels and colorbar
                xlabel('Column Index y 5 * 0.01 scale');
                ylabel('Row Index z 5 * 0.01 scale');
                colorbar;
                title('Differences Heatmap');
                
                figure;
                plot3(X(:), Y(:), differences(:), 'o');
                xlabel('Column Index (revol_b)');
                ylabel('Row Index (revol_a)');
                zlabel('Difference');
                title('Differences 3D Plot');
                
                % Create a surface plot
                figure;
                surf(X, Y, differences);
                xlabel('Column Index (revol_b)');
                ylabel('Row Index (revol_a)');
                zlabel('Difference');
                title('Differences Surface Plot');
            end
                 
        end

        % Button pushed function: AverageDiagramButton
        function AverageDiagramButtonPushed(app, event)
                         input_dimension  = app.ModeEditField.Value;
                                 input_axis =  app.AxisEditField.Value;
                                 input_string_len = app.LengthEditField.Value;
                    
                    
                    step_sizes_cell_len = strsplit(input_string_len, ',');        
                    len_x = str2double(step_sizes_cell_len{1});
                    len_y = str2double(step_sizes_cell_len{2});
                    len_z = str2double(step_sizes_cell_len{3});
                    
                    
                    %% the movement's direction is determined in step's being positive or negative
                                                                
                    input_string_step = app.Step_sizeEditField.Value;
                    
                    step_sizes_cell = strsplit(input_string_step, ',');
                    
                    
                    step_x = str2double(step_sizes_cell{1});
                    step_y = str2double(step_sizes_cell{2});
                    step_z = str2double(step_sizes_cell{3});
                    len_a = 0;
                    len_b = 0;
                    step_a = 0;
                    step_b = 0;
                    if strcmp(input_dimension, "2D")
                         %%disp('Initiate 2D scan');
                         if strcmp(input_axis, "110")
                             input_axis_2D = "xy";
                             len_a = len_x;
                             len_b = len_y;
                             step_a = step_x;
                             step_b = step_y;
                         elseif strcmp(input_axis, "101")
                             input_axis_2D = "xz";
                             len_a = len_x;
                             len_b = len_z;
                             step_a = step_x;
                             step_b = step_z;
                         elseif strcmp(input_axis, "011")
                             input_axis_2D = "yz";
                             len_a = len_y;
                             len_b = len_z;
                             step_a = step_y;
                             step_b = step_z;
                         end
                                end
                                revol_a = len_a/step_a;
                                revol_b = len_b/step_b;
                                twoD_hifft(revol_a,revol_b);


            function twoD_hifft(revol_a, revol_b)
                  differences = zeros(revol_a, revol_b);

                % Iterate over each MATLAB variable
                for i = 1:revol_a
                    for j = 1:revol_b
            
                    % Load the MATLAB variable from the MAT file
                    filename = fullfile('C:\Users\Jinzhi Shen\Desktop\fusf_redo\test_scan', sprintf('matlab_%d_%d.mat', i,j));
                    loaded_data = load(filename);  % Load the variable from the MAT file
                    data = loaded_data.wave;
            
                    % Sort the data in ascending order
                    sorted_data = sort(data(:));
            
                    % Calculate the average of the maximum ten values
                    max_avg = mean(sorted_data(end-9:end));
            
                    % Calculate the average of the minimum ten values
                    min_avg = mean(sorted_data(1:10));
            
                    % Calculate the difference using the averages
                    differences(i,j) = max_avg - min_avg;
                    end
                end


                [X, Y] = meshgrid(1:revol_b, 1:revol_a);
                % Reverse the y-axis direction
                %%differences = flipud(differences);
                
                % Plot the differences as a 2D color map
                imagesc(differences);
                
                % Add labels and colorbar
                xlabel('Column Index y 5 * 0.01 scale');
                ylabel('Row Index z 5 * 0.01 scale');
                colorbar;
                title('Differences Heatmap');
                
                figure;
                plot3(X(:), Y(:), differences(:), 'o');
                xlabel('Column Index (revol_b)');
                ylabel('Row Index (revol_a)');
                zlabel('Difference');
                title('Differences 3D Plot');
                
                % Create a surface plot
                figure;
                surf(X, Y, differences);
                xlabel('Column Index (revol_b)');
                ylabel('Row Index (revol_a)');
                zlabel('Difference');
                title('Differences Surface Plot');
            end
        end

        % Button pushed function: RelocatelocationbyuserButton
        function RelocatelocationbyuserButtonPushed(app, event)
                % Open the file for reading
                fid = fopen('C:\Users\Jinzhi Shen\Desktop\fusf_redo\aaa.txt', 'r');
                
                if fid == -1
                    error('Unable to open file for reading');
                end
                
                % Read len_x, len_y, len_z from the file
                res_x = fscanf(fid, 'len_x: %d\n', 1);
                res_y = fscanf(fid, 'len_y: %d\n', 1);
                res_z = fscanf(fid, 'len_z: %d\n', 1);
                
                % Close the file
                fclose(fid);
                
                %% move_motor back
                if ~isempty(res_x)
                 motor_mover(-res_x, 'x');
                end
                
                if ~isempty(res_y)
                 motor_mover(-res_y, 'y');
                end
                
                if ~isempty(res_z)
                 motor_mover(-res_z, 'z');
                end
                
                % Open the file for writing (clearing previous content)
                fid = fopen('C:\Users\Jinzhi Shen\Desktop\fusf_redo\aaa.txt', 'w');
                
                if fid == -1
                    error('Unable to open file for writing');
                end
                
                % Close the file
                fclose(fid);


function motor_mover(step_size,input_axis_1D)
s = serialport("COM6", 115200);
    s.OutputBufferSize = 20;
    if (step_size > 0) 
    padded_step_size = num2str(step_size,'%04.f');
    else
    % Pad the step_size with zeros
    a = num2str(abs(step_size), '%03.f');

    % Add '1' at the beginning of the padded string
    padded_step_size = ['1', a(end-2:end)];
    end

    %%1:+x 2:-x 3:+y 4:-y 5:+z 6:-z 
    %% change here for different operation
    if strcmp(input_axis_1D,"x")
        message = [padded_step_size, ',0000,0000'];
    elseif strcmp(input_axis_1D, "y")
        message = ['0000,', padded_step_size,',0000'];
    elseif strcmp(input_axis_1D, "z")
        message = ['0000,', '0000,',padded_step_size];
    end


    while true
        if s.NumBytesAvailable == 0
            write(s, message, 'char');


            %%disp("Sent to Arduino: " + message);
            pause(5);

        else 
            data = read(s,10,"string");
            disp(data);
            break;
        end


    end
end

        end

        % Button pushed function: DisplayfirstFFTButton
        function DisplayfirstFFTButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartScanButton = uibutton(app.UIFigure, 'push');
            app.StartScanButton.ButtonPushedFcn = createCallbackFcn(app, @StartScanButtonPushed, true);
            app.StartScanButton.Text = 'Start Scan';
            app.HighpassFFTButton = uibutton(app.UIFigure, 'push');
            app.HighpassFFTButton.ButtonPushedFcn = createCallbackFcn(app, @HighpassFFTButtonPushed, true);
            app.HighpassFFTButton.Text = 'High-pass FFT ';
            app.AverageDiagramButton = uibutton(app.UIFigure, 'push');
            app.AverageDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @AverageDiagramButtonPushed, true);
            app.AverageDiagramButton.Text = 'Average Diagram';
            app.ModeEditFieldLabel = uilabel(app.UIFigure);
            app.ModeEditFieldLabel.Text = 'Mode';
            app.ModeEditField = uieditfield(app.UIFigure, 'text');
            app.ModeEditField.Value = '2D';
            app.D2D3DLabel = uilabel(app.UIFigure);
            app.D2D3DLabel.Text = '1D/2D/3D';
            app.AxisEditFieldLabel = uilabel(app.UIFigure);
            app.AxisEditFieldLabel.Text = 'Axis';
            app.AxisEditField = uieditfield(app.UIFigure, 'text');
            app.AxisEditField.Value = '011';
            app.three01inorderxyzex011meansyzLabel = uilabel(app.UIFigure);
            app.three01inorderxyzex011meansyzLabel.Text = {'three 0/1 in order'; 'xyz, ex: 011 means yz'};
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.UIFigure, 'text');
            app.LengthEditField.Value = '0,240,40';
            app.Threelengthrepresentionmovementofmotorsonxyzex0750Label = uilabel(app.UIFigure);
            app.Threelengthrepresentionmovementofmotorsonxyzex0750Label.Text = {'Three length represention'; 'movement of motors on'; 'x,y,z, ex: 0,75,0'};
            app.Step_sizeEditFieldLabel = uilabel(app.UIFigure);
            app.Step_sizeEditFieldLabel.Text = 'Step_size';
            app.Step_sizeEditField = uieditfield(app.UIFigure, 'text');
            app.Step_sizeEditField.Value = '0,2,2';
            app.Infoonstep_sizeoneachaxisinUnitstepLabel = uilabel(app.UIFigure);
            app.Infoonstep_sizeoneachaxisinUnitstepLabel.Text = {'Info on step_size on each axis'; 'in Unit step'};
            app.LowpassFFTButton = uibutton(app.UIFigure, 'push');
            app.LowpassFFTButton.ButtonPushedFcn = createCallbackFcn(app, @LowpassFFTButtonPushed, true);
            app.LowpassFFTButton.Text = 'Low-pass FFT';
            app.RelocatelocationbyuserButton = uibutton(app.UIFigure, 'push');
            app.RelocatelocationbyuserButton.ButtonPushedFcn = createCallbackFcn(app, @RelocatelocationbyuserButtonPushed, true);
            app.RelocatelocationbyuserButton.Text = 'Re-locate/location by user';
            app.Input_FFTFILTERLabel = uilabel(app.UIFigure);
            app.Input_FFTFILTERLabel.Text = {'Input_FFT(FILTER)'; ''};
            app.Input_FFTFILTEREditField = uieditfield(app.UIFigure, 'text');
            app.Input_FFTFILTEREditField.Value = '15';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.DisplayfirstFFTButton = uibutton(app.UIFigure, 'push');
            app.DisplayfirstFFTButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayfirstFFTButtonPushed, true);
            app.DisplayfirstFFTButton.Text = 'Display first FFT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_scanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end